classdef vectofil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        CopyrightGkhanKomarlgithubcomelectricalgorithmLabel  matlab.ui.control.Label
        DontworryYoucanchangetheimagelateroraddnoisetoitLabel  matlab.ui.control.Label
        InputImageCanvas           matlab.ui.control.Image
        PleasechooseanimagetoprocessLabel  matlab.ui.control.Label
        VectofilLabel              matlab.ui.control.Label
        HellowelcometoLabel        matlab.ui.control.Label
        InputImageBrowseButton     matlab.ui.control.Button
        ImageLocationTextBox       matlab.ui.control.EditField
        CenterPanel                matlab.ui.container.Panel
        NoiseText                  matlab.ui.control.Label
        NoiseLabel                 matlab.ui.control.Label
        ColorShowLamp              matlab.ui.control.Lamp
        PerformTheNoiseButton      matlab.ui.control.Button
        NoisyImageCanvas           matlab.ui.control.Image
        PercentageLabel            matlab.ui.control.Label
        NoiseWantSwitch            matlab.ui.control.Switch
        UnderLineDesign            matlab.ui.control.Label
        NoiseAdditionLabel         matlab.ui.control.Label
        BlueLabel                  matlab.ui.control.Label
        GreenLabel                 matlab.ui.control.Label
        RedLabel                   matlab.ui.control.Label
        ColourLabel                matlab.ui.control.Label
        BlueSpinner                matlab.ui.control.Spinner
        GreenSpinner               matlab.ui.control.Spinner
        RedSpinner                 matlab.ui.control.Spinner
        NoisePercentageSlider      matlab.ui.control.Slider
        RightPanel                 matlab.ui.container.Panel
        FilteringStatusLamp        matlab.ui.control.Lamp
        PerformTheFilteringButton  matlab.ui.control.Button
        SaveTheProcess             matlab.ui.control.Button
        SaveTheResults             matlab.ui.control.Button
        FilteredImageCanvas        matlab.ui.control.Image
        WindowSizeKnob             matlab.ui.control.DiscreteKnob
        bvdfFilteringLAbel         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        IsNoiseWanted = true; % To share if the user wants noise or not.
        ImageItself           % Variable to hold image uint8 matrix.
        NoisyImage            % Variable to hold noisy image.
        NoiseColour = [255, 255, 255];
        NoisePercentage = 0; 
        FilteringWindowSize = 3;
        FilteredImage
    end
    
    methods (Access = public)
        
        function NoiseAddition(app)
            app.NoisyImage = app.ImageItself;
    
            % Travel throught every pixel.
            for index = 1:size(app.ImageItself, 1)
                for jndex = 1:size(app.ImageItself, 2)
                    
                    % Randomly creating a vector size of channel size.
                    random_vector = randi(255, size(app.ImageItself, 3), 1);
                    
                    % Accoring to percentage, make the interval for each channel.
                    limit_down = floor(random_vector.*((100-app.NoisePercentage)/100));
                    limit_up = floor(random_vector.*((100+app.NoisePercentage)/100));
                    interval = [limit_down, limit_up];
                    
                    % Setting the vector negative numbers as 0 (black),
                    % and the number greater then 255 as 255 (white).
                    interval(interval < 0) = 0;
                    interval(interval > 255) = 255;
                    
                    reshaped_area = reshape(app.ImageItself(index, jndex, :), [], size(app.ImageItself, 3))';
                    
                    % Check condition if its in interval for assign noise.
                    if (min((interval(:, 1) < reshaped_area)) && ...
                        min(reshaped_area < interval(:, 2)))
                        app.NoisyImage(index, jndex, :) = app.NoiseColour;
                    end
                end
            end
            
            app.NoisyImageCanvas.ImageSource = app.NoisyImage;
        end
        
        function [sorted_vectors, sorted_angles] = bvdf_sort_vectors(app, vectors)
        % BVDF_VECTOR_SORT A function to sort the given n-dimensional vectors.
        % Parameter vectors has to be a matrix which every column represents a
        % vector. Function returns a vector, first element is the sorted vectors'
        % matrix, and the second element is the sorted angles.
        
            % Create a zero-matrix for results, and assign our vectors into. First
            % row for the angles, and the remaining rows for the vectors. I'm
            % making this way, because sorting function works only this way.
            angles = zeros(size(vectors, 1) + 1, size(vectors, 2));
            angles(2:end, :) = vectors;
            
            % Choose the reference point for calculation.
            for index = 1:size(vectors, 2)
                % Travel every element to find angle according to reference element.
                for jndex = 1:size(vectors, 2)
                    
                    vectorA = vectors(:, index);
                    vectorB = vectors(:, jndex);
                    
                    % If it's the same element, do not calculate angle because it's
                    % zero.
                    if vectorA == vectorB
                        continue;
                    end
                    
                    % Add the indexth elements' angle for each element angle.
                    angles(1, index) = angles(1, index) + acosd(dot(vectorA, vectorB) / (norm(vectorA) * norm(vectorB)));
                end
            end
            
            % Sort process according the first row.
            [sorted_angles, order] = sort(angles(1, :));
            sorted_vectors = angles(2:end, order);
        end
        
        function filtered_image = bvdf_filter(app, image, sample_dims)
        % BVDF_FILTER Function to filter the image according the BVDF rule.
            if nargin < 2
                error("You have to give the image as uint8 matrix, and dimensions of the vectoral process.");
            end
            
            color_count = size(image, 3);
            image_X = size(image, 1);
            image_Y = size(image, 2);
            
            starting_points = [ceil(sample_dims/2), ceil(sample_dims/2)];
            choosing_distance = [floor(sample_dims/2), floor(sample_dims/2)];
            filtered_image = zeros(image_X, image_Y, color_count);
            
            for x_index = starting_points(1):1:(image_X-starting_points(1))
                for y_index = starting_points(2):1:(image_Y-starting_points(2))
                    
                    area2filter = image(x_index-choosing_distance(1):x_index+choosing_distance(2),...
                                        y_index-choosing_distance(2):y_index+choosing_distance(2), :);
                                    
                    area2filter = im2double(area2filter).*255;
                    
                    vectors_list = reshape(area2filter , [], 3)';
                    sorted_vectors = app.bvdf_sort_vectors(vectors_list);
                    filtered_image(x_index, y_index, :) = sorted_vectors(:, 1);
                end
            end
            
            filtered_image = cast(filtered_image, 'uint8');
            
        end
        
    end
    
    methods (Access = private)
        
        function BVDFfilter(app)
            app.FilteringStatusLamp.Color = [1, 0, 0];
            
            app.FilteredImage = app.bvdf_filter(app.NoisyImage, app.FilteringWindowSize);
            
            app.FilteredImageCanvas.ImageSource = app.FilteredImage;
            app.FilteringStatusLamp.Color = [0, 1, 0];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ColorShowLamp.Color = app.NoiseColour./255;
            app.RedSpinner.Value = app.NoiseColour(1);
            app.GreenSpinner.Value = app.NoiseColour(2);
            app.BlueSpinner.Value = app.NoiseColour(3);
            app.NoiseText.Text = sprintf("%%%d", app.NoisePercentage);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {521, 521, 521};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {521, 521};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {335, '1x', 336};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: InputImageBrowseButton
        function InputImageBrowseButtonPushed(app, event)
            % Get the location and image within a dialog window.
            [in_img_name, in_img_location] = uigetfile({'*.png'; '*.jpg'; '*.jpeg';});
            app.ImageItself = imread(fullfile(in_img_location, in_img_name));
            
            % Add the image to image canvas for user.
            app.InputImageCanvas.ImageSource = app.ImageItself;
            app.InputImageCanvas.Visible = 'on';
            app.InputImageCanvas.Enable = 'on';
            
            % Show the radio button of noise.
            app.NoiseWantSwitch.Visible = 'on';
            app.NoiseWantSwitch.Enable = 'on';
            
            % Change the button name browse.
            app.InputImageBrowseButton.Text = "Another!";
            
            % Put the file location into the box of location.
            app.ImageLocationTextBox.Value = fullfile(in_img_location, in_img_name);
            
            % Enable the selection of noise.
            app.NoiseWantSwitch.Enable = 'on';
            
            % Enable the filtering section.
            app.WindowSizeKnob.Enable = 'on';
            app.PerformTheFilteringButton.Enable = 'on';
            app.FilteringStatusLamp.Enable = 'on';
        end

        % Value changed function: NoiseWantSwitch
        function NoiseWantSwitchValueChanged(app, event)
            value = app.NoiseWantSwitch.Value;
            if (value == "Salt, pepper, noise!")
                app.PercentageLabel.Enable = 'on';
                app.NoisePercentageSlider.Enable = 'on';
                
                app.ColorShowLamp.Enable = 'on';
                app.ColourLabel.Enable = 'on';
                app.BlueLabel.Enable = 'on';
                app.BlueSpinner.Enable = 'on';
                app.RedLabel.Enable = 'on';
                app.RedSpinner.Enable = 'on';
                app.GreenLabel.Enable = 'on';
                app.GreenSpinner.Enable = 'on';
                
                app.NoiseLabel.Enable = 'on';
                app.NoiseText.Enable = 'on';
                
                app.PerformTheNoiseButton.Enable = 'on';
            else
                app.PercentageLabel.Enable = 'off';
                app.NoisePercentageSlider.Enable = 'off';
                
                app.ColorShowLamp.Enable = 'off';
                app.ColourLabel.Enable = 'off';
                app.BlueLabel.Enable = 'off';
                app.BlueSpinner.Enable = 'off';
                app.RedLabel.Enable = 'off';
                app.RedSpinner.Enable = 'off';
                app.GreenLabel.Enable = 'off';
                app.GreenSpinner.Enable = 'off';
                
                app.NoiseLabel.Enable = 'off';
                app.NoiseText.Enable = 'off';
                
                app.PerformTheNoiseButton.Enable = 'off';
            end
            
        end

        % Value changing function: RedSpinner
        function RedSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.NoiseColour = [changingValue, app.NoiseColour(2), app.NoiseColour(3)];
            app.ColorShowLamp.Color = app.NoiseColour./255;
        end

        % Value changing function: GreenSpinner
        function GreenSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.NoiseColour = [app.NoiseColour(1), changingValue, app.NoiseColour(3)];
            app.ColorShowLamp.Color = app.NoiseColour./255;
        end

        % Value changing function: BlueSpinner
        function BlueSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.NoiseColour = [app.NoiseColour(1), app.NoiseColour(2), changingValue];
            app.ColorShowLamp.Color = app.NoiseColour./255;
        end

        % Value changing function: NoisePercentageSlider
        function NoisePercentageSliderValueChanging(app, event)
            changingValue = event.Value;
            app.NoisePercentage = changingValue;
            app.NoiseText.Text = sprintf("%%%d", app.NoisePercentage);
        end

        % Button pushed function: PerformTheNoiseButton
        function PerformTheNoiseButtonPushed(app, event)
            NoiseAddition(app);
            app.NoisyImageCanvas.Visible = 'on';
            app.NoisyImageCanvas.Enable = 'on';
            app.WindowSizeKnob.Visible = 'on';
            app.WindowSizeKnob.Enable = 'on';
            app.FilteringStatusLamp.Enable = 'on';
            app.FilteringStatusLamp.Visible = 'on';
        end

        % Value changed function: WindowSizeKnob
        function WindowSizeKnobValueChanged(app, event)
            value = app.WindowSizeKnob.Value;
            if (value == "Stop") 
                app.FilteringWindowSize = 3;
            else
                app.FilteringWindowSize = str2double(value);
            end
        end

        % Button pushed function: PerformTheFilteringButton
        function PerformTheFilteringButtonPushed(app, event)
            BVDFfilter(app);
            app.FilteredImageCanvas.Enable = 'on';
            app.FilteredImageCanvas.Visible = 'on';
            app.SaveTheResults.Enable = 'on';
            app.SaveTheResults.Visible = 'on';
            app.SaveTheProcess.Enable = 'on';
            app.SaveTheProcess.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImageLocationTextBox = uieditfield(app.LeftPanel, 'text');
            app.ImageLocationTextBox.Placeholder = 'Maybe, wanna paste a URL?';
            app.InputImageBrowseButton = uibutton(app.LeftPanel, 'push');
            app.InputImageBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageBrowseButtonPushed, true);
            app.InputImageBrowseButton.Text = 'Browse';
            app.HellowelcometoLabel = uilabel(app.LeftPanel);
            app.HellowelcometoLabel.Text = 'Hello, welcome to ';
            app.VectofilLabel = uilabel(app.LeftPanel);
            app.VectofilLabel.Text = 'Vectofil+';
            app.PleasechooseanimagetoprocessLabel = uilabel(app.LeftPanel);
            app.PleasechooseanimagetoprocessLabel.Text = 'Please choose an image to process.';
            app.InputImageCanvas = uiimage(app.LeftPanel);
            app.InputImageCanvas.Enable = 'off';
            app.InputImageCanvas.Visible = 'off';
            app.DontworryYoucanchangetheimagelateroraddnoisetoitLabel = uilabel(app.LeftPanel);
            app.DontworryYoucanchangetheimagelateroraddnoisetoitLabel.Text = 'Don''t worry! You can change the image later, or add noise to it.';
            app.CopyrightGkhanKomarlgithubcomelectricalgorithmLabel = uilabel(app.LeftPanel);
            app.CopyrightGkhanKomarlgithubcomelectricalgorithmLabel.Text = '2021 Copyright. Gökhan Koçmarlı. github.com/electricalgorithm';
            app.CenterPanel = uipanel(app.GridLayout);
            app.NoisePercentageSlider = uislider(app.CenterPanel);
            app.NoisePercentageSlider.ValueChangingFcn = createCallbackFcn(app, @NoisePercentageSliderValueChanging, true);
            app.NoisePercentageSlider.Enable = 'off';
            app.RedSpinner = uispinner(app.CenterPanel);
            app.RedSpinner.ValueChangingFcn = createCallbackFcn(app, @RedSpinnerValueChanging, true);
            app.RedSpinner.Limits = [0 255];
            app.RedSpinner.RoundFractionalValues = 'on';
            app.RedSpinner.Enable = 'off';
            app.GreenSpinner = uispinner(app.CenterPanel);
            app.GreenSpinner.ValueChangingFcn = createCallbackFcn(app, @GreenSpinnerValueChanging, true);
            app.GreenSpinner.Limits = [0 255];
            app.GreenSpinner.RoundFractionalValues = 'on';
            app.GreenSpinner.Enable = 'off';
            app.BlueSpinner = uispinner(app.CenterPanel);
            app.BlueSpinner.ValueChangingFcn = createCallbackFcn(app, @BlueSpinnerValueChanging, true);
            app.BlueSpinner.Limits = [0 255];
            app.BlueSpinner.RoundFractionalValues = 'on';
            app.BlueSpinner.Enable = 'off';
            app.ColourLabel = uilabel(app.CenterPanel);
            app.ColourLabel.Enable = 'off';
            app.ColourLabel.Text = 'Colour';
            app.RedLabel = uilabel(app.CenterPanel);
            app.RedLabel.Enable = 'off';
            app.RedLabel.Text = 'Red';
            app.GreenLabel = uilabel(app.CenterPanel);
            app.GreenLabel.Enable = 'off';
            app.GreenLabel.Text = 'Green';
            app.BlueLabel = uilabel(app.CenterPanel);
            app.BlueLabel.Enable = 'off';
            app.BlueLabel.Text = 'Blue';
            app.NoiseAdditionLabel = uilabel(app.CenterPanel);
            app.NoiseAdditionLabel.Text = 'noise addition';
            app.UnderLineDesign = uilabel(app.CenterPanel);
            app.UnderLineDesign.Text = '____________________';
            app.NoiseWantSwitch = uiswitch(app.CenterPanel, 'slider');
            app.NoiseWantSwitch.Items = {'No need for nosie.', 'Salt, pepper, noise!'};
            app.NoiseWantSwitch.ValueChangedFcn = createCallbackFcn(app, @NoiseWantSwitchValueChanged, true);
            app.NoiseWantSwitch.Enable = 'off';
            app.NoiseWantSwitch.Value = 'No need for nosie.';
            app.PercentageLabel = uilabel(app.CenterPanel);
            app.PercentageLabel.Enable = 'off';
            app.PercentageLabel.Text = 'Percentage';
            app.NoisyImageCanvas = uiimage(app.CenterPanel);
            app.NoisyImageCanvas.Enable = 'off';
            app.NoisyImageCanvas.Visible = 'off';
            app.PerformTheNoiseButton = uibutton(app.CenterPanel, 'push');
            app.PerformTheNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @PerformTheNoiseButtonPushed, true);
            app.PerformTheNoiseButton.Enable = 'off';
            app.PerformTheNoiseButton.Text = 'Perform the Noise';
            app.ColorShowLamp = uilamp(app.CenterPanel);
            app.ColorShowLamp.Enable = 'off';
            app.NoiseLabel = uilabel(app.CenterPanel);
            app.NoiseLabel.Enable = 'off';
            app.NoiseLabel.Text = 'Noise';
            app.NoiseText = uilabel(app.CenterPanel);
            app.NoiseText.Enable = 'off';
            app.NoiseText.Text = '%100';
            app.RightPanel = uipanel(app.GridLayout);
            app.bvdfFilteringLAbel = uilabel(app.RightPanel);
            app.bvdfFilteringLAbel.Text = {'bvdf'; 'filtering'};
            app.WindowSizeKnob = uiknob(app.RightPanel, 'discrete');
            app.WindowSizeKnob.Items = {'3', '5', '7', '9'};
            app.WindowSizeKnob.ValueChangedFcn = createCallbackFcn(app, @WindowSizeKnobValueChanged, true);
            app.WindowSizeKnob.Enable = 'off';
            app.WindowSizeKnob.Value = '3';
            app.FilteredImageCanvas = uiimage(app.RightPanel);
            app.FilteredImageCanvas.Enable = 'off';
            app.FilteredImageCanvas.Visible = 'off';
            app.SaveTheResults = uibutton(app.RightPanel, 'push');
            app.SaveTheResults.Enable = 'off';
            app.SaveTheResults.Text = 'Save the Result';
            app.SaveTheProcess = uibutton(app.RightPanel, 'push');
            app.SaveTheProcess.Enable = 'off';
            app.SaveTheProcess.Text = 'Save The Process';
            app.PerformTheFilteringButton = uibutton(app.RightPanel, 'push');
            app.PerformTheFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @PerformTheFilteringButtonPushed, true);
            app.PerformTheFilteringButton.Enable = 'off';
            app.PerformTheFilteringButton.Text = 'Perform the Filtering';
            app.FilteringStatusLamp = uilamp(app.RightPanel);
            app.FilteringStatusLamp.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vectofil

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end